classdef calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        ComputePanel  matlab.ui.container.Panel
        plus_2        matlab.ui.control.Button
        EditField     matlab.ui.control.NumericEditField
        Label         matlab.ui.control.Label
        Spinner_2     matlab.ui.control.Spinner
        Spinner       matlab.ui.control.Spinner
        minus         matlab.ui.control.Button
        divide        matlab.ui.control.Button
        times         matlab.ui.control.Button
        plus          matlab.ui.control.Button
        instruction   matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plus
        function plusPushed(app, event)
            a=app.Spinner_2.Value;
            b=app.Spinner.Value;
            c=a+b;
            app.EditField.Value=c;


        end

        % Button pushed function: minus
        function minusButtonPushed(app, event)
            a=app.Spinner_2.Value;
            b=app.Spinner.Value;
            c=a-b;
            app.EditField.Value=c;
        end

        % Button pushed function: times
        function timesButtonPushed(app, event)
            a=app.Spinner_2.Value;
            b=app.Spinner.Value;
            c=a*b;
            app.EditField.Value=c;
        end

        % Button pushed function: divide
        function divideButtonPushed(app, event)
           a=app.Spinner_2.Value;
            b=app.Spinner.Value;
            c=a/b;
            app.EditField.Value=c;
        end

        % Button pushed function: plus_2
        function plus_2ButtonPushed(app, event)
%             a=app.Spinner_2.Value;
%             b=app.Spinner.Value;

            a=0
            b=0
            c=0

           app.Spinner_2.Value=a;
           app.Spinner.Value=b;
           app.EditField.Value=c;
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.instruction = uieditfield(app.UIFigure, 'text');
            app.instruction.Value = 'Type both numbers and press button to compute answer';
            app.ComputePanel = uipanel(app.UIFigure);
            app.ComputePanel.Title = 'Compute';
            app.plus = uibutton(app.ComputePanel, 'push');
            app.plus.ButtonPushedFcn = createCallbackFcn(app, @plusPushed, true);
            app.plus.Text = '+';
            app.times = uibutton(app.ComputePanel, 'push');
            app.times.ButtonPushedFcn = createCallbackFcn(app, @timesButtonPushed, true);
            app.times.Text = '×';
            app.divide = uibutton(app.ComputePanel, 'push');
            app.divide.ButtonPushedFcn = createCallbackFcn(app, @divideButtonPushed, true);
            app.divide.Text = '÷';
            app.minus = uibutton(app.ComputePanel, 'push');
            app.minus.ButtonPushedFcn = createCallbackFcn(app, @minusButtonPushed, true);
            app.minus.Text = '−';
            app.Spinner = uispinner(app.ComputePanel);
            app.Spinner_2 = uispinner(app.ComputePanel);
            app.Label = uilabel(app.ComputePanel);
            app.Label.Text = '=';
            app.EditField = uieditfield(app.ComputePanel, 'numeric');
            app.plus_2 = uibutton(app.ComputePanel, 'push');
            app.plus_2.ButtonPushedFcn = createCallbackFcn(app, @plus_2ButtonPushed, true);
            app.plus_2.Text = 'AC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end